classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        minButton              matlab.ui.control.Button
        secButton              matlab.ui.control.Button
        WakeButton_2           matlab.ui.control.Button
        REMButton_2            matlab.ui.control.Button
        SWSButton_2            matlab.ui.control.Button
        ISButton               matlab.ui.control.Button
        UIEEG                  matlab.ui.control.UIAxes
        sendtoworkspaceButton  matlab.ui.control.Button
        secButton_2            matlab.ui.control.Button
        zoom_in                matlab.ui.control.Button
        zoom_out               matlab.ui.control.Button
        minButton_2            matlab.ui.control.Button
        RightPanel             matlab.ui.container.Panel
        LoadDataButton         matlab.ui.control.Button
        dataname               matlab.ui.control.Label
        loadingstatusLabel     matlab.ui.control.Label
        UIVideo                matlab.ui.control.UIAxes
        MovementsLabel         matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        current_win % Description
        time % Description
        EEG
        t_frames % Description
        frames % Description
        f_path
        roi_x % Description
        roi_y % Description
        dist
        sleep_score % Description
        n_labeled
        r_dif
        data_name
        vid
        file_dev % portion of file to be read
        t_plot % plotting
        EEG_plot % plotting
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.loadingstatusLabel.Text='Update the code, then press the Load Data';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {514, 514};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {529, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            % loading the data %%%%%%%%%%%%%%%%%%%%%%
            %% setting addresses and parameters
            app.loadingstatusLabel.Text='data being loaded...';
            app.loadingstatusLabel.FontColor='g';
            
            
            light_off_frame=28768; %%%%%%%%%%%% lights-off frame number
            folder_path='Z:\hameddata2\16 chnl EEG\w041 and w043\vids\w041'; %%%%%%%%%% read videofile from here
            fname='w041-05-01-2022_00265-converted'; %%%%%%%%%%%% video file name
            dir_path_server='Z:\hameddata2\16 chnl EEG\w041 and w043\ephys\chronic_2022-01-05_20-55-56-only-w041'; %%%%%%%%
            dir_path='G:\Hamed\zf\P1\w041'; %%%%%%%%%%%%% save results here
            dir_prefix='150'; %%%%%%%%%%%%%%
            
            
            app.file_dev=1; %%%%%%%%%%%%%%%%% which prtion of EEG file you want to read? 10 for ane tenth of the file
            chnl_order=[1 2 3 4 5 6 8 7 10 9 11 12 13 16 14 15]; % 1 2 3 4 5 6 8 7 10 9 11 12 13 16 14 15
            
            
            [~,app.data_name,~]=fileparts(folder_path);
            app.dataname.Text=['Data: ' app.data_name];
            %% preparing the app.EEG and video for the GUI
            
            app.f_path=[folder_path '\' fname '.avi'];
            app.vid=VideoReader(app.f_path);
            
            f0=1; % 1st frame
            fn=ceil(app.vid.FrameRate*app.vid.Duration/app.file_dev)-1000;  %%%%%%%%%% last frame %%%%%%%%% /10
            app.frames=f0: fn; % frames to be analyzed
            app.roi_y=1:1024;  app.roi_x=1:1280; % where is the region of interest?
            
            mkdir(dir_path);
            
            % initializing the sleep_score structure
            str_size = round(app.frames(end)/60)+1000;  %number of elements
            sleep_score_(str_size) = struct('time',[],'label',[],'EEG',[],'movement',[]);
            app.sleep_score=sleep_score_;
            app.n_labeled=0;
            
            % app.time frames for app.video frames: loading synchroniying ADC channel
            app.loadingstatusLabel.Text='app.time stamps being loaded...';
            downsamp_ratio=1; % must be a power of 2, as the file reader reads blocks of 1024 samples each app.time
            [ ADC, time_adc, ~]=OpenEphys2MAT_load_save_Data(1, [dir_prefix '_ADC'], downsamp_ratio, app.file_dev,...
                dir_path_server);
            %Extracting synchroniying app.times of frames
            app.loadingstatusLabel.Text='app.time stamps being extracted...';
            [peak_indx]=find(ADC.*(ADC>4));
            jumps_to_new_frame_indx=[diff(peak_indx)>5; true];
            unique_peak_indx=peak_indx(jumps_to_new_frame_indx);
            t_frames_=time_adc(unique_peak_indx);
            clear unique_peak_indx jumps_to_new_frame_indx ADC time_adc peak_indx
            app.t_frames=t_frames_(app.frames);
            t_light_off=app.t_frames(light_off_frame-f0+1);
            
            % load app.EEG as .continuous
            app.loadingstatusLabel.Text='app.EEG being loaded...';
            app.loadingstatusLabel.FontColor='k';
            downsamp_ratio=64; %%%%%%%%% must be a power of 2, as the file reader reads blocks of 1024 samples each app.time
            [ EEG_, app.time, ~]=OpenEphys2MAT_load_save_Data(chnl_order, [dir_prefix '_CH'], downsamp_ratio, app.file_dev,...
                dir_path_server);
            app.EEG=zscore(EEG_);
            app.dist=2;
            app.loadingstatusLabel.Text='app.EEG data loaded';
            app.current_win=app.t_frames(1)+5; % first window-center app.time
            
            
            % Update EEG and video display
            [app.r_dif]=display_eeg_video(app.current_win, app.time, app.EEG, app.UIEEG, app.dist, app.t_frames, app.frames, app.vid...
                , app.roi_y, app.roi_x, app.UIVideo); % update the figures
            
            
            % updating the sleep time indicator
            t_plot_lim=[app.current_win-4.5 app.current_win+4.5];  % app.time for plotting
            win_frames=app.frames(app.t_frames>t_plot_lim(1)+3 & app.t_frames<t_plot_lim(2)-3);
            current_place=100*win_frames(end)/app.frames(end);
            line(app.UIAxes,[current_place current_place],[0 1],'linewidth',2);
            xlim(app.UIAxes,[0 100]);
            
        end

        % Button pushed function: secButton
        function secButtonPushed(app, event)
            app.current_win=app.current_win+3; % first window-center app.time
            
            % Update EEG and video display
            [app.r_dif]=display_eeg_video(app.current_win, app.time, app.EEG, app.UIEEG, app.dist, app.t_frames, app.frames, app.vid...
                , app.roi_y, app.roi_x, app.UIVideo); % update the figures
            
            % updating the sleep time indicator
            t_plot_lim=[app.current_win-4.5 app.current_win+4.5];  % app.time for plotting
            win_frames=app.frames(app.t_frames>t_plot_lim(1)+3 & app.t_frames<t_plot_lim(2)-3);
            current_place=100*win_frames(end)/app.frames(end);
            line(app.UIAxes,[current_place current_place],[0 1],'linewidth',2);
            xlim(app.UIAxes,[0 100]);
            
        end

        % Button pushed function: minButton
        function minButtonPushed(app, event)
            app.current_win=app.current_win+20*60;
            
            % Update EEG and video display
            [app.r_dif]=display_eeg_video(app.current_win, app.time, app.EEG, app.UIEEG, app.dist, app.t_frames, app.frames, app.vid...
                , app.roi_y, app.roi_x, app.UIVideo); % update the figures
            
            % updating the sleep time indicator
            t_plot_lim=[app.current_win-4.5 app.current_win+4.5];  % app.time for plotting
            win_frames=app.frames(app.t_frames>t_plot_lim(1)+3 & app.t_frames<t_plot_lim(2)-3);
            current_place=100*win_frames(end)/app.frames(end);
            line(app.UIAxes,[current_place current_place],[0 1],'linewidth',2);
            xlim(app.UIAxes,[0 100]);
            
            % deliver the variables to the workspace
            assignin('base','sleep_scores',app.sleep_score);
        end

        % Callback function
        function submitButtonPushed(app, event)
            
        end

        % Button pushed function: WakeButton_2
        function WakeButton_2Pushed(app, event)
            app.n_labeled=app.n_labeled+1;
            label = 'Wake';
            % write the result in the sleep_score
            app.sleep_score(app.n_labeled).time=app.current_win; % center of the time window
            app.sleep_score(app.n_labeled).label=label;
            EEG_ind=(app.time>app.current_win-2.5 & app.time<app.current_win+2.5);
            app.sleep_score(app.n_labeled).EEG=app.EEG(EEG_ind,:);
            app.sleep_score(app.n_labeled).movement=app.r_dif;
        end

        % Button pushed function: REMButton_2
        function REMButton_2Pushed(app, event)
            app.n_labeled=app.n_labeled+1;
            label = 'REM';
            % write the result in the sleep_score
            app.sleep_score(app.n_labeled).time=app.current_win; % center of the time window
            app.sleep_score(app.n_labeled).label=label;
            EEG_ind=(app.time>app.current_win-2.5 & app.time<app.current_win+2.5);
            app.sleep_score(app.n_labeled).EEG=app.EEG(EEG_ind,:);
            app.sleep_score(app.n_labeled).movement=app.r_dif;
        end

        % Button pushed function: SWSButton_2
        function SWSButton_2Pushed(app, event)
            app.n_labeled=app.n_labeled+1;
            label = 'SWS';
            % write the result in the sleep_score
            app.sleep_score(app.n_labeled).time=app.current_win; % center of the time window
            app.sleep_score(app.n_labeled).label=label;
            EEG_ind=(app.time>app.current_win-2.5 & app.time<app.current_win+2.5);
            app.sleep_score(app.n_labeled).EEG=app.EEG(EEG_ind,:);
            app.sleep_score(app.n_labeled).movement=app.r_dif;
        end

        % Button pushed function: ISButton
        function ISButtonPushed(app, event)
            app.n_labeled=app.n_labeled+1;
            label = 'IS';
            % write the result in the sleep_score
            app.sleep_score(app.n_labeled).time=app.current_win; % center of the time window
            app.sleep_score(app.n_labeled).label=label;
            EEG_ind=(app.time>app.current_win-2.5 & app.time<app.current_win+2.5);
            app.sleep_score(app.n_labeled).EEG=app.EEG(EEG_ind,:);
            app.sleep_score(app.n_labeled).movement=app.r_dif;
        end

        % Button pushed function: sendtoworkspaceButton
        function sendtoworkspaceButtonPushed(app, event)
            assignin('base','sleep_scores',app.sleep_score);
        end

        % Button pushed function: secButton_2
        function secButton_2Pushed(app, event)
            app.current_win=app.current_win-3; % first window-center app.time
            
            % Update EEG and video display
            display_eeg_video(app.current_win, app.time, app.EEG, app.UIEEG, app.dist, app.t_frames, app.frames, app.vid...
                , app.roi_y, app.roi_x, app.UIVideo); % update the figures
            
            % updating the sleep time indicator
            t_plot_lim=[app.current_win-4.5 app.current_win+4.5];  % app.time for plotting
            win_frames=app.frames(app.t_frames>t_plot_lim(1)+3 & app.t_frames<t_plot_lim(2)-3);
            current_place=100*win_frames(end)/app.frames(end);
            line(app.UIAxes,[current_place current_place],[0 1],'linewidth',2);
            xlim(app.UIAxes,[0 100]);
            
        end

        % Button pushed function: zoom_out
        function zoom_outPushed(app, event)
            app.dist=app.dist+.2*app.dist;
            % Update EEG and video display
            display_eeg_video(app.current_win, app.time, app.EEG, app.UIEEG, app.dist, app.t_frames, app.frames, app.vid...
                , app.roi_y, app.roi_x, app.UIVideo); % update the figures
        end

        % Button pushed function: zoom_in
        function zoom_inButtonPushed(app, event)
            app.dist=app.dist-.2*app.dist;
            % Update EEG and video display
            display_eeg_video(app.current_win, app.time, app.EEG, app.UIEEG, app.dist, app.t_frames, app.frames, app.vid...
                , app.roi_y, app.roi_x, app.UIVideo); % update the figures
        end

        % Button pushed function: minButton_2
        function minButton_2Pushed(app, event)
            app.current_win=app.current_win-20*60;
            
            % Update EEG and video display
            [app.r_dif]=display_eeg_video(app.current_win, app.time, app.EEG, app.UIEEG, app.dist, app.t_frames, app.frames, app.vid...
                , app.roi_y, app.roi_x, app.UIVideo); % update the figures
            
            % updating the sleep time indicator
            t_plot_lim=[app.current_win-4.5 app.current_win+4.5];  % app.time for plotting
            win_frames=app.frames(app.t_frames>t_plot_lim(1)+3 & app.t_frames<t_plot_lim(2)-3);
            current_place=100*win_frames(end)/app.frames(end);
            line(app.UIAxes,[current_place current_place],[0 1],'linewidth',2);
            xlim(app.UIAxes,[0 100]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.minButton = uibutton(app.LeftPanel, 'push');
            app.minButton.ButtonPushedFcn = createCallbackFcn(app, @minButtonPushed, true);
            app.minButton.Text = '>>20min';
            app.secButton = uibutton(app.LeftPanel, 'push');
            app.secButton.ButtonPushedFcn = createCallbackFcn(app, @secButtonPushed, true);
            app.secButton.Text = '> 3sec';
            app.WakeButton_2 = uibutton(app.LeftPanel, 'push');
            app.WakeButton_2.ButtonPushedFcn = createCallbackFcn(app, @WakeButton_2Pushed, true);
            app.WakeButton_2.Text = 'Wake';
            app.REMButton_2 = uibutton(app.LeftPanel, 'push');
            app.REMButton_2.ButtonPushedFcn = createCallbackFcn(app, @REMButton_2Pushed, true);
            app.REMButton_2.Text = 'REM';
            app.SWSButton_2 = uibutton(app.LeftPanel, 'push');
            app.SWSButton_2.ButtonPushedFcn = createCallbackFcn(app, @SWSButton_2Pushed, true);
            app.SWSButton_2.Text = 'SWS';
            app.ISButton = uibutton(app.LeftPanel, 'push');
            app.ISButton.ButtonPushedFcn = createCallbackFcn(app, @ISButtonPushed, true);
            app.ISButton.Text = 'IS';
            app.UIEEG = uiaxes(app.LeftPanel);
            title(app.UIEEG, 'EEG')
            xlabel(app.UIEEG, 'Time (sec)')
            ylabel(app.UIEEG, '')
            app.UIEEG.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIEEG.YTick = [];
            app.sendtoworkspaceButton = uibutton(app.LeftPanel, 'push');
            app.sendtoworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @sendtoworkspaceButtonPushed, true);
            app.sendtoworkspaceButton.Text = 'send to workspace';
            app.secButton_2 = uibutton(app.LeftPanel, 'push');
            app.secButton_2.ButtonPushedFcn = createCallbackFcn(app, @secButton_2Pushed, true);
            app.secButton_2.Text = '3sec <';
            app.zoom_in = uibutton(app.LeftPanel, 'push');
            app.zoom_in.ButtonPushedFcn = createCallbackFcn(app, @zoom_inButtonPushed, true);
            app.zoom_in.Text = '+';
            app.zoom_out = uibutton(app.LeftPanel, 'push');
            app.zoom_out.ButtonPushedFcn = createCallbackFcn(app, @zoom_outPushed, true);
            app.zoom_out.Text = '-';
            app.minButton_2 = uibutton(app.LeftPanel, 'push');
            app.minButton_2.ButtonPushedFcn = createCallbackFcn(app, @minButton_2Pushed, true);
            app.minButton_2.Text = '20min<<';
            app.RightPanel = uipanel(app.GridLayout);
            app.LoadDataButton = uibutton(app.RightPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.dataname = uilabel(app.RightPanel);
            app.dataname.Text = 'data name';
            app.loadingstatusLabel = uilabel(app.RightPanel);
            app.loadingstatusLabel.Text = 'loading status';
            app.UIVideo = uiaxes(app.RightPanel);
            title(app.UIVideo, '')
            xlabel(app.UIVideo, '')
            ylabel(app.UIVideo, '')
            app.UIVideo.XTick = [];
            app.UIVideo.YTick = [];
            app.MovementsLabel = uilabel(app.RightPanel);
            app.MovementsLabel.Text = 'Movements';
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Progress (%)')
            ylabel(app.UIAxes, '')
            app.UIAxes.XLim = [0 100];
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end